classdef Radiacion_Antena_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        text1_2      matlab.ui.control.Label
        show_plots   matlab.ui.control.Button
        text1        matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        dipole1      matlab.ui.control.EditField
        text3        matlab.ui.control.Label
        wave_length  matlab.ui.control.EditField
        text2        matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
        text8_2      matlab.ui.control.Label
        text8        matlab.ui.control.Label
        plotting1    matlab.ui.control.UIAxes
        polaring1    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function theta_define_Callback(app, hObject, eventdata, handles)
            % hObject    handle to theta_define (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of theta_define as text
            %        str2double(get(hObject,'String')) returns contents of theta_define as a double
            theta= str2double(get(hObject,'string'));
            handles.theta=theta;
            if isnan(theta)
              errordlg('Debe usar un valor numérico','Bad Input','modal')
              uicontrol(hObject)
            	return
            end
            guidata(hObject,handles)
            handles.theta;
        end
        
        function theta_define_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to theta_define (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Radiacion_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Radiacion (see VARARGIN)
            
            % Choose default command line output for Radiacion
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: wave_length
        function wave_length_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to wave_length (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of wave_length as text
            %        str2double(get(hObject,'String')) returns contents of wave_length as a double
            wavelength = str2double(get(hObject,'string'));
            handles.wavelength=wavelength;
            if isnan(wavelength)
              errordlg('Debe usar un valor numérico','Bad Input','modal')
              uicontrol(hObject)
            	return
            end
            guidata(hObject,handles)
            handles.wavelength;
        end

        % Value changed function: dipole1
        function dipole1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dipole1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of dipole1 as text
            %        str2double(get(hObject,'String')) returns contents of dipole1 as a double
            dipole1 = str2double(get(hObject,'string'));
            handles.dipole1=dipole1;
            if isnan(dipole1)
              errordlg('Debe usar un valor numérico','Bad Input','modal')
              uicontrol(hObject)
            	return
            end
            guidata(hObject,handles)
            handles.dipole1;
        end

        % Button pushed function: show_plots
        function show_plots_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to show_plots (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            

            dipole1=handles.dipole1; %L
            wavelength=handles.wavelength; %Lambda
            theta=(0:.01:1)*2*pi; %Theta
            phi=(0:.01:1)*2*pi; %Phi
            [PHI, THETA] = meshgrid(phi,theta); %Phi & Theta matrix
            K=2*pi/wavelength; %Wave number
            
            E=(cos(K.*dipole1/2.*cos(THETA))-cos(K.*dipole1/2))./sin(THETA); %Pattern Factor
            
            %Polar plot
            axes(handles.polaring1)
            polar(handles.polaring1,THETA,abs(E),'r')
            
            %Lineal plot
            axes(handles.plotting1)
            plot(handles.plotting1,THETA*180/pi,abs(E),'r')

            %3D Plot
            X = E.*sin(THETA).*cos(PHI);
            Y = E.*sin(THETA).*sin(PHI);
            Z = E.*cos(THETA);
            axes(handles.plotting3d)
            mesh(handles.plotting3d, X,Y,Z);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.figure1.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {673, 673};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {377, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Radiacion';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            app.UIAxes.Tag = 'plotting3d';
            app.text2 = uilabel(app.LeftPanel);
            app.text2.Tag = 'text2';
            app.text2.Text = 'λ:';
            app.wave_length = uieditfield(app.LeftPanel, 'text');
            app.wave_length.ValueChangedFcn = createCallbackFcn(app, @wave_length_Callback, true);
            app.wave_length.Tag = 'wave_length';
            app.text3 = uilabel(app.LeftPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'L:';
            app.dipole1 = uieditfield(app.LeftPanel, 'text');
            app.dipole1.ValueChangedFcn = createCallbackFcn(app, @dipole1_Callback, true);
            app.dipole1.Tag = 'dipole1';
            app.text7 = uilabel(app.LeftPanel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Gráfico 3D';
            app.text9 = uilabel(app.LeftPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = '[m]';
            app.text10 = uilabel(app.LeftPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '[*W/L]';
            app.text1 = uilabel(app.LeftPanel);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Patrón de Radiación';
            app.show_plots = uibutton(app.LeftPanel, 'push');
            app.show_plots.ButtonPushedFcn = createCallbackFcn(app, @show_plots_Callback, true);
            app.show_plots.Tag = 'show_plots';
            app.show_plots.Text = 'Graficar';
            app.text1_2 = uilabel(app.LeftPanel);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = {'Juan Camilo Mejía Fragoso'; 'Juan Carlos Gutiérrez Barrera'};
            app.RightPanel = uipanel(app.GridLayout);
            app.polaring1 = uiaxes(app.RightPanel);
            app.polaring1.Tag = 'polaring1';
            app.plotting1 = uiaxes(app.RightPanel);
            app.plotting1.Tag = 'plotting1';
            app.text8 = uilabel(app.RightPanel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Gráfico polar';
            app.text8_2 = uilabel(app.RightPanel);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = 'Gráfico Lineal';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Radiacion_Antena_3D(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Radiacion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end